classdef controller_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SimulationControls  simulink.ui.control.SimulationControls
        Spinner_3           matlab.ui.control.Spinner
        SpinnerLabel_3      matlab.ui.control.Label
        Spinner_2           matlab.ui.control.Spinner
        SpinnerLabel_2      matlab.ui.control.Label
        Spinner             matlab.ui.control.Spinner
        SpinnerLabel        matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'input_force';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Value = 1000;
            app.SpinnerLabel_2 = uilabel(app.UIFigure);
            app.SpinnerLabel_2.Text = 'input_moment';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Value = 300;
            app.SpinnerLabel_3 = uilabel(app.UIFigure);
            app.SpinnerLabel_3.Text = 'input_theta';
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.Value = 90;
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Spinner, 'Value', app.Simulation.TunableVariables, ['input_force:' app.Simulation.ModelName]);
            bind(app.Spinner_2, 'Value', app.Simulation.TunableVariables, ['input_moment:' app.Simulation.ModelName]);
            bind(app.Spinner_3, 'Value', app.Simulation.TunableVariables, ['input_theta:' app.Simulation.ModelName]);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controller_app

            % Associate the Simulink Model
            app.Simulation = simulation('controller_main');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end